classdef DSP_Project2_AndreiD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FilterDesignDropDown         matlab.ui.control.DropDown
        FilterDesignDropDownLabel    matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        WindowTypeButtonGroup        matlab.ui.container.ButtonGroup
        KaiserButton                 matlab.ui.control.RadioButton
        HammingButton                matlab.ui.control.RadioButton
        BlackmanHarrisButton         matlab.ui.control.RadioButton
        RectangularButton            matlab.ui.control.RadioButton
        FilterTypeButtonGroup        matlab.ui.container.ButtonGroup
        BandStopFilterButton         matlab.ui.control.RadioButton
        BandPassFilterButton         matlab.ui.control.RadioButton
        HighPassFilterButton         matlab.ui.control.RadioButton
        LowPassFilterButton          matlab.ui.control.RadioButton
        ControlPanel                 matlab.ui.container.Panel
        ApplyFilterButton            matlab.ui.control.Button
        PlayUnfilteredButton         matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        DSPProject1AudioFilterLabel  matlab.ui.control.Label
        InputAxesFFT_2               matlab.ui.control.UIAxes
        InputAxes_2                  matlab.ui.control.UIAxes
        InputAxesFFT                 matlab.ui.control.UIAxes
        InputAxes                    matlab.ui.control.UIAxes
        FilterAxesPhase              matlab.ui.control.UIAxes
        FilterAxesMag                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename;   % Name of input audio file
        input;      % Input audio file
        output;     % Input audio file with the desired effects applied
        Fs;         % Sampling frequency of the input audio file
        Ts;         % Sampling period of the input audio file
        t;          % Time in seconds of the input/output audio file
        t_final;    % Max value of the input audio file
    end
    
    methods (Access = private)
        
        % Disables Control Inputs based on the length of the sound
        function DisableControl(app, sound_length)
            
            % Disable file input fields until sound stops playing
            set(app.LoadButton, 'Enable', 'off');
            set(app.PlayUnfilteredButton, 'Enable', 'off');
            set(app.ApplyFilterButton, 'Enable', 'off');
            if strcmpi(app.LoadButton.Enable, 'off')
                pause(sound_length)
                set(app.LoadButton, 'Enable', 'on');
                set(app.PlayUnfilteredButton, 'Enable', 'on');
                set(app.ApplyFilterButton, 'Enable', 'on');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Store input file and setup file properties
            [app.filename,PathName,FilterIndex] = uigetfile('.wav');
            if isnumeric(app.filename)
                return;    %user canceled
            end
            [app.input,app.Fs] = audioread(app.filename);
            app.Ts = 1/app.Fs;
            app.t_final = length(app.input)/app.Fs;
            app.t = (0:app.Ts:app.t_final-app.Ts)';

            % Note: 1xN = Mono, 2xN = Stereo
            % If input is mono, make it stereo
            if isvector(app.input)
                app.input = [app.input app.input];
            end
           
            % Play and plot input signal
            sound(app.input,app.Fs);
            plot(app.InputAxes, app.input)

            % Set the altered output sound file to input (temporary)
            app.output = app.input;

            % Dont input on file
            app.EditField.Value = app.filename;
            
            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                        
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            if isnumeric(file)
                return;    %user canceled
            end

            % Check if the user didn put anything
            if isempty(file) == 1
                saveerror = errordlg('Enter File Name','Save Error');
            else
                filename = fullfile(path, file);
                audiowrite(filename, app.output, app.Fs);
            end

        end

        % Button pushed function: PlayUnfilteredButton
        function PlayUnfilteredButtonPushed(app, event)
            
            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.input, app.Fs)
            plot(app.InputAxes, app.input)

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            
            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.output, app.Fs)
            % plot(app.UIAxes_2, app.input) % nothing to plot

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterAxesMag = uiaxes(app.UIFigure);
            title(app.FilterAxesMag, 'Frequency Response: Magnitude')
            xlabel(app.FilterAxesMag, 'Frequency (Hz)')
            ylabel(app.FilterAxesMag, 'Magnitude (dB)')
            zlabel(app.FilterAxesMag, 'Z')
            app.FilterAxesMag.YLim = [-100 20];
            app.FilterAxesPhase = uiaxes(app.UIFigure);
            title(app.FilterAxesPhase, 'Frequency Response: Phase')
            xlabel(app.FilterAxesPhase, 'Frequency (Hz)')
            ylabel(app.FilterAxesPhase, 'Phase (°)')
            zlabel(app.FilterAxesPhase, 'Z')
            app.FilterAxesPhase.YLim = [-100 20];
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Raw Signal')
            xlabel(app.InputAxes, 'Sample (n)')
            zlabel(app.InputAxes, 'Z')
            app.InputAxes.YLim = [0 1];
            app.InputAxesFFT = uiaxes(app.UIFigure);
            title(app.InputAxesFFT, 'Raw Signal FFT')
            xlabel(app.InputAxesFFT, 'Frequency (Hz)')
            zlabel(app.InputAxesFFT, 'Z')
            app.InputAxesFFT.YLim = [0 1];
            app.InputAxes_2 = uiaxes(app.UIFigure);
            title(app.InputAxes_2, 'Raw Signal')
            xlabel(app.InputAxes_2, 'Sample (n)')
            zlabel(app.InputAxes_2, 'Z')
            app.InputAxes_2.YLim = [0 1];
            app.InputAxesFFT_2 = uiaxes(app.UIFigure);
            title(app.InputAxesFFT_2, 'Raw Signal FFT')
            xlabel(app.InputAxesFFT_2, 'Frequency (Hz)')
            zlabel(app.InputAxesFFT_2, 'Z')
            app.InputAxesFFT_2.YLim = [0 1];
            app.DSPProject1AudioFilterLabel = uilabel(app.UIFigure);
            app.DSPProject1AudioFilterLabel.Text = 'DSP Project 1 - Audio Filter ';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.LoadButton = uibutton(app.ControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.ControlPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Placeholder = 'Input file name';
            app.PlayUnfilteredButton = uibutton(app.ControlPanel, 'push');
            app.PlayUnfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUnfilteredButtonPushed, true);
            app.PlayUnfilteredButton.Text = 'Play Unfiltered';
            app.ApplyFilterButton = uibutton(app.ControlPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowPassFilterButton.Text = 'Low Pass Filter';
            app.LowPassFilterButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.BandPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandPassFilterButton.Text = 'Band Pass Filter';
            app.BandStopFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandStopFilterButton.Text = 'Band Stop Filter';
            app.WindowTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.RectangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.BlackmanHarrisButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.BlackmanHarrisButton.Text = 'Blackman-Harris';
            app.HammingButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.KaiserButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.FilterDesignDropDownLabel = uilabel(app.UIFigure);
            app.FilterDesignDropDownLabel.Text = 'Filter Design';
            app.FilterDesignDropDown = uidropdown(app.UIFigure);
            app.FilterDesignDropDown.Items = {'Butterworth (Analog)', 'Chebyshev (Analog)', 'Moving Average (Digital)', 'Windowed SInc (Digital)', 'Chebyshev (Digital)'};
            app.FilterDesignDropDown.Value = 'Butterworth (Analog)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_Project2_AndreiD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end